classdef scaleAxesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ControlPanel                matlab.ui.container.Panel
        SyncSwitch                  matlab.ui.control.Switch
        ApplyButton                 matlab.ui.control.Button
        SyncLamp                    matlab.ui.control.Lamp
        SyncSwitchLabel             matlab.ui.control.Label
        CurrentrangeEditField       matlab.ui.control.EditField
        UpperEditField              matlab.ui.control.NumericEditField
        LowerEditField              matlab.ui.control.NumericEditField
        CurrentrangeEditFieldLabel  matlab.ui.control.Label
        SliderHTML                  matlab.ui.control.HTML
        AxestypeDropDown            matlab.ui.control.DropDown
        AxestypeDropDownLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        target;
        axesName;
        limits;
        currentRange;
        htmlComponent;
        syncOpt = "On";
    end
    
    methods (Access = public)
        
        function updateCurrentRange(app)
            app.CurrentrangeEditField.Value = join(string(num2str(roundn(app.currentRange, -2))), '     ');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, target, axesName, currentRange, limits)
            app.UIFigure.Name = 'UI for scaleAxes';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            app.target = target;
            app.axesName = axesName;
            app.currentRange = currentRange;
            app.limits = limits;

            data.currentRange = currentRange;
            data.min = limits(1);
            data.max = limits(2);
            data.ticks = sort(unique([0, currentRange]), 'ascend');

            app.SliderHTML.HTMLSource = fullfile(fileparts(mfilename("fullpath")), 'scaleAxes UI.html');
            app.SliderHTML.DataChangedFcn = {@htmlDataChangedFcn, app};
            app.SliderHTML.Data = data;
            app.htmlComponent = app.SliderHTML;

            app.LowerEditField.Value = limits(1);
            app.UpperEditField.Value = limits(2);

            app.AxestypeDropDown.Value = axesName;
            app.SyncLamp.Color = [0, 1, 0]; % On
            app.SyncSwitch.Value = "On";
            
            updateCurrentRange(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            scaleAxes(app.target, app.axesName, app.currentRange);
            updateCurrentRange(app);
        end

        % Value changed function: SyncSwitch
        function SyncSwitchValueChanged(app, event)
            value = app.SyncSwitch.Value;
            app.syncOpt = value;

            if strcmp(value, "On")
                app.SyncLamp.Color = [0, 1, 0];
            else
                app.SyncLamp.Color = [0.8, 0.8, 0.8];
            end
        end

        % Value changed function: LowerEditField
        function LowerEditFieldValueChanged(app, event)
            app.limits(1) = app.LowerEditField.Value;
            app.htmlComponent.Data.min = app.limits(1);

            if strcmp(app.syncOpt, "On")
                scaleAxes(app.target, app.axesName, app.currentRange);
                updateCurrentRange(app);
            end
        end

        % Value changed function: UpperEditField
        function UpperEditFieldValueChanged(app, event)
            app.limits(2) = app.UpperEditField.Value;
            app.htmlComponent.Data.max = app.limits(2);

            if strcmp(app.syncOpt, "On")
                scaleAxes(app.target, app.axesName, app.currentRange);
                updateCurrentRange(app);
            end
        end

        % Value changed function: CurrentrangeEditField
        function CurrentrangeEditFieldValueChanged(app, event)
            app.currentRange = eval(strcat("[", app.CurrentrangeEditField.Value, "]"));
            data = app.htmlComponent.Data;
            data.currentRange = app.currentRange;
            app.htmlComponent.Data = data;
            scaleAxes(app.target, app.axesName, app.currentRange);
            updateCurrentRange(app);
        end

        % Value changed function: AxestypeDropDown
        function AxestypeDropDownValueChanged(app, event)
            app.axesName = app.AxestypeDropDown.Value;
            app.currentRange = scaleAxes(app.target, app.axesName);
            app.limits = [app.currentRange(1) - 0.25 * diff(app.currentRange), ...
                          app.currentRange(2) + 0.25 * diff(app.currentRange)];
            data.currentRange = app.currentRange;
            data.min = app.limits(1);
            data.max = app.limits(2);
            data.ticks = sort([0, app.currentRange], 'ascend');
            app.htmlComponent.Data = data;
            app.LowerEditField.Value = app.limits(1);
            app.UpperEditField.Value = app.limits(2);
            updateCurrentRange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.AxestypeDropDownLabel = uilabel(app.ControlPanel);
            app.AxestypeDropDownLabel.Text = 'Axes type';
            app.AxestypeDropDown = uidropdown(app.ControlPanel);
            app.AxestypeDropDown.Items = {'x', 'y', 'z', 'c'};
            app.AxestypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AxestypeDropDownValueChanged, true);
            app.AxestypeDropDown.Value = 'x';
            app.SliderHTML = uihtml(app.ControlPanel);
            app.SliderHTML.HTMLSource = 'scaleAxes UI.html';
            app.CurrentrangeEditFieldLabel = uilabel(app.ControlPanel);
            app.CurrentrangeEditFieldLabel.Text = 'Current range';
            app.LowerEditField = uieditfield(app.ControlPanel, 'numeric');
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @LowerEditFieldValueChanged, true);
            app.UpperEditField = uieditfield(app.ControlPanel, 'numeric');
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @UpperEditFieldValueChanged, true);
            app.UpperEditField.Value = 1;
            app.CurrentrangeEditField = uieditfield(app.ControlPanel, 'text');
            app.CurrentrangeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentrangeEditFieldValueChanged, true);
            app.SyncSwitchLabel = uilabel(app.ControlPanel);
            app.SyncSwitchLabel.Text = 'Sync';
            app.SyncLamp = uilamp(app.ControlPanel);
            app.ApplyButton = uibutton(app.ControlPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SyncSwitch = uiswitch(app.ControlPanel, 'slider');
            app.SyncSwitch.ValueChangedFcn = createCallbackFcn(app, @SyncSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scaleAxesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end